classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Label               matlab.ui.control.Label
        RateSlider          matlab.ui.control.Slider
        RateSliderLabel     matlab.ui.control.Label
        VolumeSlider        matlab.ui.control.Slider
        VolumeSliderLabel   matlab.ui.control.Label
        TextEditField       matlab.ui.control.EditField
        TextEditFieldLabel  matlab.ui.control.Label
        PlayeButton         matlab.ui.control.Button
        ApplicationconversiontexteavoiceLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlayeButton
        function PlayeButtonPushed(app, event)
             if strcmp(app.TextEditField.Value,'')==1
               app.Label.Text ='Please write something in the text box !';
           else
               app.Label.Text ='';
              Text_to_Speech (app.TextEditField.Value, app.RateSlider.Value , app.VolumeSlider.Value);
            
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ApplicationconversiontexteavoiceLabel = uilabel(app.UIFigure);
            app.ApplicationconversiontexteavoiceLabel.Text = 'Application conversion texte a voice';
            app.PlayeButton = uibutton(app.UIFigure, 'push');
            app.PlayeButton.ButtonPushedFcn = createCallbackFcn(app, @PlayeButtonPushed, true);
            app.PlayeButton.Text = 'Playe';
            app.TextEditFieldLabel = uilabel(app.UIFigure);
            app.TextEditFieldLabel.Text = 'Text     ';
            app.TextEditField = uieditfield(app.UIFigure, 'text');
            app.VolumeSliderLabel = uilabel(app.UIFigure);
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.UIFigure);
            app.VolumeSlider.Value = 100;
            app.RateSliderLabel = uilabel(app.UIFigure);
            app.RateSliderLabel.Text = 'Rate';
            app.RateSlider = uislider(app.UIFigure);
            app.RateSlider.Limits = [0 5];
            app.RateSlider.Value = 2;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end